classdef Chooseway < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Panel     matlab.ui.container.Panel
        Color     matlab.ui.control.StateButton
        Feature   matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        inputImages % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            data = getappdata(0, "ChooseWay");
            app.inputImages = data.inputImage;
            featureValue = 0;
            setappdata(0, "chooseway", featureValue)      
        end

        % Value changed function: Feature
        function FeatureValueChanged(app, event)
            value = app.Feature.Value;
             if value == 1                
                feature.inputImages = imAdjust(app.inputImages);
                featureValue = 1;

                setappdata(0, "feature", feature)                
                run numPoint.mlapp;
                setappdata(0, "chooseway", featureValue)
                app.Feature.Value = 0;
             end
             delete(app.UIFigure);
        end

        % Value changed function: Color
        function ColorValueChanged(app, event)
            value = app.Color.Value;
            if value == 1
                data.inputImages = app.inputImages;
                data.title = "框选一个监测点";
                setappdata(0, "color", data)
                run Monitoring_size.mlapp;
                app.Color.Value = 0;
            end
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '选择降噪方式';
            app.Panel.Tag = 'chooseway';
            app.Feature = uibutton(app.Panel, 'state');
            app.Feature.ValueChangedFcn = createCallbackFcn(app, @FeatureValueChanged, true);
            app.Feature.Tag = 'feature';
            app.Feature.Text = '基于特征';
            app.Color = uibutton(app.Panel, 'state');
            app.Color.ValueChangedFcn = createCallbackFcn(app, @ColorValueChanged, true);
            app.Color.Tag = 'color';
            app.Color.Text = '基于颜色';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Chooseway

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end