classdef Drawfigure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Panel     matlab.ui.container.Panel
        UIAxes    matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % 接收参数
           data = getappdata(0, 'dataillstrate'); %取数据
           
           T_train = data.T_train;   
           T_test = data.T_test;
           T_sim1 = data.T_sim1;
           T_sim2 = data.T_sim2;
           M = data.M;
           N = data.N;
           error1 = data.error1;
           error2 = data.error2;
           %% 标定
            ax1 = subplot(2, 2, 1, "Parent", app.Panel);
            ax2 = subplot(2, 2, 2, "Parent", app.Panel);
            ax3 = subplot(2, 2, 3, "Parent", app.Panel);
            ax4 = subplot(2, 2, 4, "Parent", app.Panel);
            %% 绘图
            plot(ax1, 1: M, T_train, 'r-', 1: M, T_sim1, 'b-', 'LineWidth', 1)
            legend(ax1, '真实值', '预测值', 'Location','northwest', 'Fontsize', 8)
            xlabel(ax1, '预测样本', 'FontSize',8)
            ylabel(ax1, '预测结果', 'FontSize',8)
            string = {'训练集预测结果对比'; ['RMSE=' num2str(error1)]};
            title(ax1, string, 'FontSize', 8)
            xlim(ax1, [1, M])

            plot(ax2, 1: N, T_test, 'r-', 1: N, T_sim2, 'b-', 'LineWidth', 1)
            legend(ax2,'真实值', '预测值', 'Location','northwest','FontSize', 8)
            xlabel(ax2,'预测样本','FontSize', 8)
            ylabel(ax2,'预测结果','FontSize', 8)
            string = {'测试集预测结果对比'; ['RMSE=' num2str(error2)]};
            title(ax2,string, 'FontSize', 8)
            xlim(ax2,[1, N])
            %%  绘制散点图
            sz = 25;
            c = 'b';
            scatter(ax3,T_train, T_sim1, sz, c)
            hold (ax3, 'on')
            if min(T_train) < max(T_train)
                xlim(ax3, [min(T_train) max(T_train)])
            end
            if min(T_sim1) < max(T_sim1)
                ylim(ax3, [min(T_sim1) max(T_sim1)])
            end
            plot(ax3, xlim(ax3), ylim(ax3), '--k')
            xlabel(ax3,'训练集真实值', 'FontSize', 8);
            ylabel(ax3,'训练集预测值', 'FontSize', 8);

            title(ax3,'训练集预测值 vs. 训练集真实值', 'FontSize', 8)

            scatter(ax4,T_test, T_sim2, sz, c)
            hold (ax4, "on")
            if min(T_test) < max(T_test)
                xlim(ax4, [min(T_test) max(T_test)])
            end
            if min(T_sim2) < max(T_sim2)
                ylim(ax4, [min(T_sim2) max(T_sim2)])
            end
            plot(ax4, xlim(ax4), ylim(ax4), '--k')
            xlabel(ax4, '测试集真实值', 'FontSize', 8);
            ylabel(ax4, '测试集预测值', 'FontSize', 8);

            title(ax4, '测试集预测值 vs. 测试集真实值', 'FontSize', 8)           
            pause(0.01)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '算法效果显示';
            app.UIAxes = uiaxes(app.Panel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = 1;
            app.UIAxes.YTick = [];
            app.UIAxes.Tag = 'Drawfigure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Drawfigure

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end