classdef Monitoring_size < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Panel     matlab.ui.container.Panel
        UIAxes    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            data = getappdata(0, "color");
            inputImages = data.inputImages;
            title = data.title;
            % 获取监测点的长宽数据          
            imshow(inputImages, "Parent",app.UIAxes);%显示图像
             hold(app.UIAxes, "on");
            app.UIAxes.Title.String = title;
            rec2 = drawrectangle("Parent", app.UIAxes);%生成一个可拉伸的矩形
            data.p = ceil(rec2.Position);%获取拉伸后矩阵的坐标 
            data.inputImages = inputImages;
            setappdata(0, "Monitoringsize", data);
            run adjust.mlapp
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Tag = 'Monitoringsize';
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Monitoring_size

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end