classdef adjust < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        Panel_5             matlab.ui.container.Panel
        Height              matlab.ui.control.NumericEditField
        Label_8             matlab.ui.control.Label
        Widht               matlab.ui.control.NumericEditField
        Label_7             matlab.ui.control.Label
        Button_7            matlab.ui.control.Button
        Panel               matlab.ui.container.Panel
        R                   matlab.ui.control.StateButton
        Lamp                matlab.ui.control.Lamp
        G                   matlab.ui.control.StateButton
        B                   matlab.ui.control.StateButton
        Panel_2             matlab.ui.container.Panel
        RSpinnerLabel_3     matlab.ui.control.Label
        RSpinnerLabel_2     matlab.ui.control.Label
        RSpinnerLabel       matlab.ui.control.Label
        EditField_2Label_2  matlab.ui.control.Label
        EditField_2Label    matlab.ui.control.Label
        UB                  matlab.ui.control.Spinner
        UG                  matlab.ui.control.Spinner
        UR                  matlab.ui.control.Spinner
        LB                  matlab.ui.control.Spinner
        LG                  matlab.ui.control.Spinner
        LR                  matlab.ui.control.Spinner
        Button_8            matlab.ui.control.Button
        Panel_4             matlab.ui.container.Panel
        TextArea            matlab.ui.control.TextArea
        TextAreaLabel       matlab.ui.control.Label
        StrelVerify         matlab.ui.control.Button
        StrelPara           matlab.ui.control.Spinner
        Label_6             matlab.ui.control.Label
        threshold           matlab.ui.control.Spinner
        Label_3             matlab.ui.control.Label
        LowColorThreshold   matlab.ui.control.Spinner
        Label               matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        UpColorThreshold    matlab.ui.control.Spinner
        Panel_3             matlab.ui.container.Panel
        verify              matlab.ui.control.Button
        RightPanel          matlab.ui.container.Panel
        UIAxes              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        inputImage1 % Description
        inputImage2
        inputImage3
        inputImage4
        inputImage5
        %标记图像
        labeledImg 
         %区域数量
        numRegions
        pq
        PL
        ROI
        roi
        ColorPara
        
        ia

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            data = getappdata(0, "Monitoringsize");
            app.inputImage1 = data.inputImages;
            app.pq = data.p;
            imshow(app.inputImage1, "Parent",app.UIAxes);
            app.UIAxes.Title.String = "对比度调整";
            % 直方图调整
            app.inputImage1 = histeq(app.inputImage1);
            % 对比度调整
%             app.inputImage1 = imAdjust(app.inputImage1);

            imshow(app.inputImage1, "Parent",app.UIAxes);
        end

        % Button pushed function: Button_8
        function Button_6Pushed(app, event)
          
            low_R = app.LR.Value;
            low_G = app.LG.Value;
            low_B = app.LB.Value;
            high_R = app.UR.Value;
            high_G = app.UG.Value;
            high_B = app.UB.Value;
            app.inputImage1 = imadjust(app.inputImage1,[low_R, low_G, low_B; high_R, high_G, high_B], []);
            app.ColorPara = [low_R, low_G, low_B; high_R, high_G, high_B];
            % 结果显示
            imshow(app.inputImage1, "Parent", app.UIAxes)
            app.UIAxes.Title.String = "对比度结果";
        end

        % Value changed function: R
        function RValueChanged(app, event)
            value = app.R.Value;
             if value == 1
                app.G.Value = 0;
                app.B.Value = 0;
                app.Lamp.Color = 'r';
                PhotoLayer = 1;
             end
             app.inputImage2 = app.inputImage1(:, :, PhotoLayer);
             app.PL = PhotoLayer;
             % 结果显示
             imshow(app.inputImage2, "Parent", app.UIAxes)
             app.UIAxes.Title.String = "图层调整结果";
        end

        % Value changed function: G
        function GValueChanged(app, event)
            value = app.G.Value;
            if value == 1
                app.R.Value = 0;
                app.B.Value = 0;
                app.Lamp.Color = 'g';
                PhotoLayer = 2;
            end
            app.inputImage2 = app.inputImage1(:, :, PhotoLayer);
            app.PL = PhotoLayer;
            % 结果显示
            imshow(app.inputImage2, "Parent", app.UIAxes)
            app.UIAxes.Title.String = "图层调整结果";
        end

        % Value changed function: B
        function BValueChanged(app, event)
            value = app.B.Value;
            if value == 1
                app.R.Value = 0;
                app.G.Value = 0;
                app.Lamp.Color = 'b';
                PhotoLayer = 3;
            end
            app.inputImage2 = app.inputImage1(:, :, PhotoLayer);
            app.PL = PhotoLayer;
            % 结果显示
            imshow(app.inputImage2, "Parent", app.UIAxes)
            app.UIAxes.Title.String = "图层调整结果";
        end

        % Button pushed function: verify
        function verifyPushed(app, event)
           % 取反
           app.inputImage3 = app.inputImage2;
            app.inputImage3 = ColorThreshold(app.inputImage3, app.UpColorThreshold.Value, app.LowColorThreshold.Value, app.threshold.Value);
            % 结果显示
            imshow(app.inputImage3, "Parent", app.UIAxes)
            app.UIAxes.Title.String = "调整阈值";
        end

        % Button pushed function: StrelVerify
        function StrelVerifyPushed(app, event)
            Strel = app.StrelPara.Value;
            SE = strel('square',Strel);
            app.inputImage4 = app.inputImage3;

            app.inputImage4 = imfill(app.inputImage4, 'holes');
            app.inputImage4 = imerode(app.inputImage4, SE);
            % 结果显示
            imshow(app.inputImage4, "Parent", app.UIAxes)
            app.UIAxes.Title.String = "特征分离";
            [app.labeledImg, app.numRegions] = bwlabel(app.inputImage4);

            app.ROI = regionprops(app.labeledImg);

            app.ia = 0;
            for j = 1: app.numRegions
                if  app.ROI(j).BoundingBox(3) <= 1.5 * app.pq(3) && app.ROI(j).BoundingBox(3) >=0.7*app.pq(3) ...
                        && app.ROI(j).BoundingBox(4) <= 1.5 * app.pq(4) && app.ROI(j).BoundingBox(3) >= 0.7 * app.pq(4)
                    app.ia = app.ia + 1;
                    ROI2(app.ia) = app.ROI(j);
                end
            end
            app.TextArea.Value = num2str(app.ia);
           app.roi = ROI2;

            imshow(label2rgb(app.inputImage4), "Parent", app.UIAxes)
            app.UIAxes.Title.String = "显示标记";

        end

        % Button pushed function: Button_7
        function Button_5Pushed(app, event)
           app.inputImage5 = app.inputImage4;
            % 结果显示
            imshow(app.inputImage5, "Parent", app.UIAxes)
            app.UIAxes.Title.String = "框选监测点";
            hold (app.UIAxes, "on")
            for j = 1: app.ia
                cent(j, :) = app.roi(j).Centroid;
                x = app.roi(j).BoundingBox(1);
                y = app.roi(j).BoundingBox(2);
                W = app.roi(j).BoundingBox(3);
                H = app.roi(j).BoundingBox(4);
                rectangle('Position',[x, y, W, H],'EdgeColor','b','LineWidth',1, 'Parent', app.UIAxes);
            end
            FeatureCentroids = cent; % 获取特征质心
            widht =  app.Widht.Value ./  W;
            height = app.Height.Value ./ H ;
            % 返回以下11个参数给主程序
            data.p = app.pq;
            data.ColorPara = app.ColorPara;% 对比度参数
            data.PL = app.PL; %图层通道参数
            data.UCT = app.UpColorThreshold.Value; %阈值参数
            data.LCT = app.LowColorThreshold.Value; % 阈值参数
            data.threshold = app.threshold.Value;
            data.strelPa =  app.StrelPara.Value; %形态学参数
            data.inputImages = app.inputImage5; % 图像输出
            data.FeatureCentroids = FeatureCentroids; % 特征中心
            data.widht = widht; 
            data.height = height;
            setappdata(0, "adjust", data);
            % 关闭当前页面
            delete(app.UIFigure)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.Tag = 'adjust';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel_3 = uipanel(app.LeftPanel);
            app.Panel_3.Title = '颜色阈值';
            app.verify = uibutton(app.Panel_3, 'push');
            app.verify.ButtonPushedFcn = createCallbackFcn(app, @verifyPushed, true);
            app.verify.Text = '确认';
            app.UpColorThreshold = uispinner(app.LeftPanel);
            app.UpColorThreshold.Limits = [0 255];
            app.UpColorThreshold.Value = 200;
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '上限';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '下限';
            app.LowColorThreshold = uispinner(app.LeftPanel);
            app.LowColorThreshold.Limits = [0 255];
            app.LowColorThreshold.Value = 80;
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '阈值3';
            app.threshold = uispinner(app.LeftPanel);
            app.threshold.Limits = [0 255];
            app.threshold.Value = 200;
            app.Panel_4 = uipanel(app.LeftPanel);
            app.Panel_4.Title = '特征分离';
            app.Label_6 = uilabel(app.Panel_4);
            app.Label_6.Text = '形态学参数';
            app.StrelPara = uispinner(app.Panel_4);
            app.StrelPara.Limits = [0 100];
            app.StrelPara.Value = 5;
            app.StrelVerify = uibutton(app.Panel_4, 'push');
            app.StrelVerify.ButtonPushedFcn = createCallbackFcn(app, @StrelVerifyPushed, true);
            app.StrelVerify.Text = '确认';
            app.TextAreaLabel = uilabel(app.Panel_4);
            app.TextAreaLabel.Text = '监测点数量';
            app.TextArea = uitextarea(app.Panel_4);
            app.Panel_2 = uipanel(app.LeftPanel);
            app.Panel_2.Title = '对比度';
            app.Button_8 = uibutton(app.Panel_2, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_8.Text = '确认';
            app.LR = uispinner(app.Panel_2);
            app.LR.Step = 0.1;
            app.LR.Limits = [0 1];
            app.LR.Value = 0.1;
            app.LG = uispinner(app.Panel_2);
            app.LG.Step = 0.1;
            app.LG.Limits = [0 1];
            app.LG.Value = 0.1;
            app.LB = uispinner(app.Panel_2);
            app.LB.Step = 0.1;
            app.LB.Limits = [0 1];
            app.LB.Value = 0.9;
            app.UR = uispinner(app.Panel_2);
            app.UR.Step = 0.1;
            app.UR.Limits = [0 1];
            app.UR.Value = 1;
            app.UG = uispinner(app.Panel_2);
            app.UG.Step = 0.1;
            app.UG.Limits = [0 1];
            app.UG.Value = 1;
            app.UB = uispinner(app.Panel_2);
            app.UB.Step = 0.1;
            app.UB.Limits = [0 1];
            app.UB.Value = 1;
            app.EditField_2Label = uilabel(app.Panel_2);
            app.EditField_2Label.Text = '下限';
            app.EditField_2Label_2 = uilabel(app.Panel_2);
            app.EditField_2Label_2.Text = '上限';
            app.RSpinnerLabel = uilabel(app.Panel_2);
            app.RSpinnerLabel.Text = 'R';
            app.RSpinnerLabel_2 = uilabel(app.Panel_2);
            app.RSpinnerLabel_2.Text = 'G';
            app.RSpinnerLabel_3 = uilabel(app.Panel_2);
            app.RSpinnerLabel_3.Text = 'B';
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = '图层';
            app.B = uibutton(app.Panel, 'state');
            app.B.ValueChangedFcn = createCallbackFcn(app, @BValueChanged, true);
            app.B.Text = 'B';
            app.G = uibutton(app.Panel, 'state');
            app.G.ValueChangedFcn = createCallbackFcn(app, @GValueChanged, true);
            app.G.Text = 'G';
            app.Lamp = uilamp(app.Panel);
            app.R = uibutton(app.Panel, 'state');
            app.R.ValueChangedFcn = createCallbackFcn(app, @RValueChanged, true);
            app.R.Text = 'R';
            app.Panel_5 = uipanel(app.LeftPanel);
            app.Panel_5.Title = '坐标换算';
            app.Button_7 = uibutton(app.Panel_5, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_7.Text = '确定';
            app.Label_7 = uilabel(app.Panel_5);
            app.Label_7.Text = '监测点尺寸：宽';
            app.Widht = uieditfield(app.Panel_5, 'numeric');
            app.Widht.Value = 30;
            app.Label_8 = uilabel(app.Panel_5);
            app.Label_8.Text = '监测点尺寸：高';
            app.Height = uieditfield(app.Panel_5, 'numeric');
            app.Height.Value = 30;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adjust

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end