classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        V20Panel           matlab.ui.container.Panel
        Panel_12           matlab.ui.container.Panel
        TextArea           matlab.ui.control.TextArea
        Panel_3            matlab.ui.container.Panel
        TextArea_4         matlab.ui.control.TextArea
        Label_10           matlab.ui.control.Label
        Lamp               matlab.ui.control.Lamp
        countersign        matlab.ui.control.Button
        ChooseWay          matlab.ui.control.Button
        TabGroup2          matlab.ui.container.TabGroup
        Tab_4              matlab.ui.container.Tab
        mEditField         matlab.ui.control.EditField
        mEditField_2Label  matlab.ui.control.Label
        TextArea_6         matlab.ui.control.TextArea
        Label_17           matlab.ui.control.Label
        TextArea_5         matlab.ui.control.TextArea
        Label_16           matlab.ui.control.Label
        cumputer_point     matlab.ui.control.Spinner
        datan              matlab.ui.control.NumericEditField
        Label_13           matlab.ui.control.Label
        intern             matlab.ui.control.NumericEditField
        Label_12           matlab.ui.control.Label
        pn                 matlab.ui.control.NumericEditField
        Label_11           matlab.ui.control.Label
        sButton            matlab.ui.control.Button
        sTextArea          matlab.ui.control.TextArea
        predictValue       matlab.ui.control.NumericEditField
        Label_8            matlab.ui.control.Label
        moniter_point      matlab.ui.control.Spinner
        Spinner_7Label_3   matlab.ui.control.Label
        Lamp2              matlab.ui.control.Lamp
        Button_12          matlab.ui.control.StateButton
        sEditField         matlab.ui.control.NumericEditField
        sLabel             matlab.ui.control.Label
        Button_8           matlab.ui.control.Button
        Tab_5              matlab.ui.container.Tab
        Button_15          matlab.ui.control.Button
        Button_13          matlab.ui.control.Button
        UITable            matlab.ui.control.Table
        Tab_6              matlab.ui.container.Tab
        Button_14          matlab.ui.control.Button
        UITable2           matlab.ui.control.Table
        DrawFigure         matlab.ui.container.Panel
        Input              matlab.ui.control.UIAxes
        DisplayPath        matlab.ui.container.Panel
        help               matlab.ui.control.Button
        loading            matlab.ui.control.Button
        OpenFiles          matlab.ui.control.Button
        EditField          matlab.ui.control.EditField
        EditFieldLabel     matlab.ui.control.Label
        ContextMenu        matlab.ui.container.ContextMenu
        aboutsolftware     matlab.ui.container.Menu
        link               matlab.ui.container.Menu
    end


    properties (Access = public)
        %% 标定
        ax1
        ax2
        ax3
        ax4
        % 定义用于存放矩形区域的单元体数组
        roi_rects = {};
        folderPath % 路径
        % 定义支持的图像文件扩展名
        imageExtensions = {'*.jpg', '*.jpeg', '*.png', '*.bmp', '*.tif', '*.tiff'};

        % 初始化文件列表
        fileList = [];
%         %监测点数量
%         num_rois
%         path  % 读取文件路径
        %存储图片
%         inputImages
        inputImage
%         inputImageOr

        %累计位移
        CumDisplacement
        speed        
        % 处理第一张图片获得的参数
        Fig1Para
        % 当前状态，具体类型取决于你的应用
        CurrentState
        % 选择网络
        value
        % 选择处理方式 ,ChooseValue = 1,采用特征对比，ChooseValue = 0,选择颜色分割
        ChooseValue
        % 网络参数

        imds %
        % 绘制目标框坐标
        p
        pq
        % 图片行
        rows
        %图片列
        cols
        % 图层数量
        channel
        Phopra
        % 对比度调整结果
        imad
        %测量图像特征
        ROI
%         %ROI宽度
%         W
%         %ROI高度
%         H
       %监测点宽度修正，一个像素几厘米宽
        widht
        %监测点高度修正，一个像素几厘米高
        height
        %质心坐标
        centroidData = struct('FileName', {}, 'FeatureCentroids', {});
        %特征腐蚀参数
        StrelPara
        %间隔时间
        interval
        % 标签
        CurveLabels
        %计数
        numi
        % 字符向量元胞数组
        cellArrayText
        cellArrayText2

        itxt = 0;  % 计数器

        x0;
        X1;
        x1;
        rho;
        result1;
        tX;
        relative_residuals;
        eta;
        test_num;
        FS = 8; %字号大小
        test_x0;
        name = '公路边坡蠕滑监测预报系统V1.0';
        CumdisplacementTableData % 用于存储累积位移表格数据
        timerObj; % 定时器对象
        IsPaused = false % 暂停状态标记


    end

    methods (Access = public)

        %% 函数1，字符输出函数
        function outPut(app,str)
            % 将数据格式化为字符串或字符向量
            string = sprintf('%s\n', str);
            % 水平串联数组
            app.cellArrayText=horzcat(app.cellArrayText,string);
            % 给TextArea赋值
            app.TextArea.Value = app.cellArrayText;
        end

        %% 函数2，字符输出函数
        function outPut2(app,str)
            % 将数据格式化为字符串或字符向量
            string = sprintf('%s\n', str);
            % 水平串联数组
            app.cellArrayText2=horzcat(app.cellArrayText2,string);
            % 给TextArea赋值
            app.TextArea_4.Value = app.cellArrayText2;
        end

        %% 函数3，gm(1,1)模型
        function [result, x0_hat, relative_residuals, eta] = gm11(app, x0, predict_num)
            % 函数作用：使用传统的GM(1,1)模型对数据进行预测
            %     x0：要预测的原始数据
            %     predict_num： 向后预测的期数
            % 输出变量 
            %     result：预测值
            %     x0_hat：对原始数据的拟合值
            %     relative_residuals： 对模型进行评价时计算得到的相对残差
            %     eta： 对模型进行评价时计算得到的级比偏差

            [n,m] = size(x0);

            x_1=cumsum(x0); % 计算一次累加值
            z1 = (x_1(1:end-1,:) + x_1(2:end, :)) ./ 2;  % 计算紧邻均值生成数列（长度为n-1）
            % 将从第二项开始的x0当成y，z1当成x，来进行一元回归  y = kx +b
            y = x0(2:end, :); x = z1;
            % 下面的表达式就是第四讲拟合里面的哦~ 但是要注意，此时的样本数应该是n-1，少了一项哦
            k = ((n-1)*sum(x.*y)-sum(x) .* sum(y)) ./ ((n-1)*sum(x.*x)-sum(x) .* sum(x));
            b = (sum(x.*x).*sum(y)-sum(x).*sum(x.*y)) ./ ((n-1)*sum(x.*x)-sum(x).*sum(x));
            a = -k;  %注意：k = -a
            % -a是发展系数,  b是灰作用量

            x0_hat=zeros(n,m);    % x0_hat向量用来存储对x0序列的拟合值，
            x0_hat(1, :)=x0(1, :);   % x0_hat向量用来存储对x0序列的拟合值，初始化
            for j = 1: n-1
                x0_hat(j+1, :) = (1-exp(a)) .* (x0(1, :)-b./a).*exp(-a*j);
            end
            result = zeros(predict_num, m);  % 初始化用来保存预测值的向量
            for i = 1: predict_num
                result(i, :) = (1-exp(a)).*(x0(1, :)-b./a).*exp(-a*(n+i-1)); % 带入公式直接计算
            end

            % 计算绝对残差和相对残差
            % 从第二项开始计算绝对残差，因为第一项是相同的
            absolute_residuals = x0(2:end, :) - x0_hat(2:end, :);
            % 计算相对残差，注意分子要加绝对值，而且要使用点除
            relative_residuals = abs(absolute_residuals) ./ x0(2:end, :);
            % 计算级比和级比偏差
            class_ratio = x0(2:end, :) ./ x0(1:end-1, :) ;  % 计算级比 sigma(k) = x0(k)/x0(k-1)
            eta = abs(1-(1-0.5*a)./(1+0.5*a).*(1./class_ratio));  % 计算级比偏差

            %% 对全0列或常数列处理,如果是常数列或k小于一定值，则预测值和拟合值均为最后一个值
            %残差和级比偏差全为0；
            zC = all(x0 == 0, 1); % 找出全为0的列
            x0_hat(:, zC) = 0;
            result(:, zC) = 0;
            relative_residuals(:, zC) = 0;
            eta(:, zC) = 0;

            kC = k < exp(-16);
            if any (kC)
                a(find(kC)) = 0;
            end
            for pj = 1: n-1
                x0_hat(pj+1, find(a == 0)) =  x0(end,  find(a == 0));
            end

            for i = 1: predict_num
                result(i, find(a == 0)) =  x0(end, find(a == 0)); % 带入公式直接计算
            end

        end

        %% 函数4， 遍历所有支持的扩展名，获取文件列表

        function fileList = filelistfcn(app, imageExtensions, folderPath, fileList)
            for ext = imageExtensions
                currentFiles = dir(fullfile(folderPath, ext{1}));
                fileList = [fileList; currentFiles];
            end
        end

        %% 函数4，累积位移数据绘图
        function CumDisplacementPlot(app, ax, X, CumDisplacement, FS, n)
             % 累积位移绘图参数说明：1，ax2-绘图坐标区，X-横坐标，CumDisplacement-累积位移，FS-字体大小，n-检测点数量
            plot(ax, X,CumDisplacement)
            legendLabels = arrayfun(@(i) sprintf('Monitoring Point %d', i), 1:n, 'UniformOutput', false) ;
            legend(ax, legendLabels, 'Location', 'northwest', 'FontSize', FS)
            ylim(ax, "auto") ;
            xlabel(ax, "time (s)" ,"FontSize", FS);
            ylabel(ax, "Cumulative displacement (cm)","FontSize", FS);
            string1 =  "Monitoring Point Cumulative displacement";
            title(ax, string1 ,"FontSize", FS)
        end

      %% 函数5，位移速度绘图
        function SpeedPlot(app, ax,X, speed, FS, n)
            plot(ax, X, speed)
            legendLabels = arrayfun(@(i) sprintf('Monitoring Point %d', i), 1:n, 'UniformOutput', false) ;         
            legend(ax, legendLabels, 'Location', 'northwest', 'FontSize', FS)
            ylim(ax, "auto");
            xlabel(ax, "time (s)","FontSize", FS)
            ylabel(ax, "Speed (cm/s)","FontSize", FS)
            string2 = "Monitoring Points Speed";
            title(ax, string2,"FontSize", FS)
        end
    %% 函数5，位移切线角绘图
        function AnglePlot(app, ax, Xangle, angle, ia, FS)
            plot(ax, Xangle, angle, 'r', LineWidth=2);
            legendLabels = arrayfun(@(i) sprintf('Monitoring Point %d', i), 1:ia, 'UniformOutput', false) ;
            %legendLabels = cellstr(legendLabels{:}) % 转换为cell数组
            legend(ax, legendLabels, 'Location', 'northwest', 'FontSize', FS)
            ylim(ax, "auto");
            xlabel(ax, "time (s)","FontSize", FS)
            ylabel(ax, " tangential angle ","FontSize", FS)
            string2 = "Monitoring Points Speed";
            title(ax, string2,"FontSize",FS)
        end

        %% 函数6，基于特征计算最佳位置
        function [p, bestMatch] = CalculateNewSite(app, inputImage, num_rois, dX, dY, p2, mask1)
            inputImages = imAdjust(inputImage);
            [a, b, ~] = size(inputImages);

            for k = 1: num_rois
                % 预分配内存
                roi2 = cell(numel(dX), 1);
                mask2 = cell(numel(dX), 1);
                % 遍历指定范围内的所有点
                for i_dx = 1: numel(dX)
                    dX(i_dx) = min(dX(i_dx), b - p2{k}(1) - p2{k}(3));
                    dY(i_dx) = min(dY(i_dx), a - p2{k}(2) - p2{k}(4));
                    roi2{i_dx} = p2{k} + [dX(i_dx), dY(i_dx), 0, 0];
                    roi2{i_dx}(roi2{i_dx}(:) < 1) = 1; % 确保坐标不小于1
                    mask2{i_dx} = inputImages(roi2{i_dx}(2):roi2{i_dx}(2)+roi2{i_dx}(4),roi2{i_dx}(1):roi2{i_dx}(1)+roi2{i_dx}(3), :);
                end
                % 预分配一个得分
                score = zeros(1, length(mask2));
                % 并行计算得分
                parfor iscore = 1: length(mask2)
                    % 计算f范数，彩色图用
                    score(iscore) = norm( single(mask1{k} - mask2{iscore}), 'fro');
                end

                % 计算最佳得分及其索引
                [bestScore, imatch] = min(score);
                bestMatch{k} = roi2{imatch};
                % 更新最优选框位置
                p{k} = bestMatch{k};
            end
        end

        %% 函数7，显示监测点位置
        function cent = DisplayPoint(app, i, inputImage, ax, FS, num_rois, bestMatch)
                        imshow(inputImage, 'Parent', ax);
                        title(ax, i, "FontSize", FS);
                         hold(ax, "on")
                         cent = zeros(num_rois, 2);
                        for k = 1: num_rois
                            rectangle('Position', bestMatch{k}, 'EdgeColor', 'b', 'LineWidth', 2, "Parent", ax);
                            cent(k, 1) = bestMatch{k}(1) + bestMatch{k}(3)/2;% 横坐标
                            cent(k, 2) = bestMatch{k}(2) + bestMatch{k}(4)/2;% 纵坐标
                            app.CurveLabels = {};
                            text(ax, cent(k, 1), cent(k, 2), num2str(k), 'Color', 'r', 'FontSize', FS, ...
                                'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                            label = sprintf('Monitoring Point %d', k);
                            app.CurveLabels{end+1} = label;
                        end
        end

        %% 函数8， 准指数规律检验
        function [x1, rho, index1, index2] = QuasiExponentialLaw(app, x0, n)
            outPut(app,'------------------------------------------------------------');
            outPut(app,'准指数规律检验');
            x1 = cumsum(x0);   % 生成1-AGO序列，cumsum是累加函数~    注意：1.0e+03 *0.1740的意思是科学计数法,即10^3*0.1740 = 174
            rho = x0(2:end, :) ./ x1(1:end-1, :) ; % 计算光滑度rho(k) = x0(k)/x1(k-1)

            index1 = 100*sum(rho<0.5, 1) ./ (n-1);
            index2 = 100*sum(rho(3:end, :)<0.5, 1) ./ (n-3);
        end

        %% 函数8，预测结果绘图

        function PredictPlot(app, ax, X, data, preX, X1, x0_hat, result, site2, FS)
            h1 = plot(ax, X,data,'-or',"DisplayName",'Raw data') ;

            hold(ax,"on");
        
            h2 = plot(ax,  X1,x0_hat,'-*m',"DisplayName", '  fitted data');
            hold(ax,"on");
            h3 = plot(ax,  preX ,result,'-*b',"DisplayName",' predicted data');

            all_handles = findobj(ax, 'Type', 'line');
            legend_handles = all_handles(1: size(data,2): size(data, 2)*3);
            legend(ax, legend_handles, 'Location', 'northwest', 'FontSize', FS);
            h4 = xline(ax, site2, '--r','LineWidth',2,"DisplayName",' accelerated stage limit');
            ylim(ax, "auto");
            % set(p,gca,'xtick',[X1(1):20:X1(end)+predict_num])  % 设置x轴横坐标的间隔为20
            xlabel(ax, 'time (s)',"FontSize",FS);
            ylabel(ax, 'Cumulative displacement (cm)',"FontSize", FS);  % 给坐标轴加上标签
            string3 = "Cumulative displacement predict";
            title(ax, string3,"FontSize", FS)
            drawnow;
        end

        %% 函数9，预测到达路面的时间
        function PredictLandslideTime(app, Value, result, interval)

            landslideTime = find(Value > max(result));
            landslideTime2 = find(Value < max(result));
            if landslideTime
                outPut2(app, ["前方边坡" num2str(landslideTime) "存在滑坡风险"]);
            end
            if landslideTime2
                indices = find(result(:, landslideTime2) > Value(landslideTime2), 1, "first");
                t_landslides = (indices - 1) .* interval;
                str = ["前方边坡第" num2str(landslideTime2) "个监测点所在位置发生明显滑坡，预计还有", num2str(t_landslides), 's到达路面'];
                outPut2(app, str);
            end
        end


        %% 函数5，嵌套函数：处理图像文件
        function processImages(app, t, folderPath, X, p1,rows, cols,  num_rois, p2, CumDisplacement, vectorC1, ...
                imageExtensions, interval, ChooseValue, dX, dY, mask1, pixel2cm, ax1, ax2, ax3, ax4, FS, pn, ...
                intern, datan, centData, predict_num, site2, angle, Xangle, Value)
            % t 已经处理过的图像的数量
            % folderPath 文件夹路径
            % X 横坐标
            % p1 图像裁剪参数
            % num_rois 监测点数量
            % p2 监测点的参数
            % 累积位移
            % VectorC1 判断累积位移小于该值时更新累积位移为这个值
            % interval 图像拍摄间隔时间
            % ChooseValue 选择的图片处理模式
            % pixel2cm 将图上尺寸换算为实际尺寸
            % ax1, ax2, ax3, ax4 子图
            % FS 字体大小
            % pn 起始数据量
            % intern 预测间隔
            % itxt 预测次数
            % datan 样本数据量
            % site2 加速阶段预测位置
            % angle 位移角
            % Xangle 位移角的横坐标


            % 定义一个持久变量，用于计算依据处理的图片数量
            persistent lastProcessedCount
            % 初始化上次处理计数
            if isempty(lastProcessedCount)
                lastProcessedCount = t;
            end
            
            % 定义支持的图像文件扩展名
% 
            % 初始化文件列表
            app.fileList = [];

            % 遍历所有支持的扩展名，获取文件列表
            for ext = imageExtensions
                currentFiles = dir(fullfile(folderPath, ext{1}));
                app.fileList = [app.fileList; currentFiles];
            end

            % 打印当前文件数量（调试信息）
           outPut(app, ['当前文件夹中的文件数量: %d\n', length(app.fileList)]);

            % 检查是否有新文件
            if length(app.fileList) > lastProcessedCount
                 outPut(app, ['发现' num2str(length(app.fileList) - lastProcessedCount) '个新图像文件，开始处理...']);

                for i = (lastProcessedCount + 1):length(app.fileList)
                          fileName = fullfile(folderPath, app.fileList(i).name);
%         try
            % 读取图像
            X = [X, i * interval];
            app.numi = i;
            inputImages = imread(fileName);
            %裁剪和调整对比度
            inputImageOr = inputImages(p1(2):min(p1(2)+p1(4), rows), p1(1):min(p1(1)+p1(3), cols), :);
               
                %% 基于特征
                if ChooseValue == 1                   
                        [p2, bestMatch] = CalculateNewSite(app, inputImageOr, num_rois, dX, dY, p2, mask1);                      
%                         cent = DisplayPoint(app, app.fileList(i).date, inputImageOr, app.ax1, app.FS, num_rois, bestMatch);
                           strp = ['Picture', num2str(i)];
                           cent = DisplayPoint(app,  strp, inputImageOr, ax1, FS, num_rois, bestMatch);

                    centData{2} = centData{3};
                    centData{3} = cent;

                    %% 基于颜色

                elseif ChooseValue == 0

                    inputImages = histeq(inputImageOr);
                    inputImages = imadjust(inputImages, app.Fig1Para.ColorPara, []);
                    inputImages = inputImages(:, :, PhotoLayer);
                    inputImages = ColorThreshold( inputImages, app.Fig1Para.UCT, app.Fig1Para.LCT, app.Fig1Para.threshold);

                    % 将特征进行腐蚀和膨胀，使特征分离开
                    SE = strel('square',app.Fig1Para.strelPa);
                    inputImages = imfill(inputImages, 'holes');
                    inputImages = imerode(inputImages, SE);
                    [labeledImg, numRegions] = bwlabel(inputImages);  % 分割图像，并返回标记图像和区域数量
                    app.ROI = regionprops(labeledImg);   % 测量图像区域的属性,像素大小，边界框，质心
                    %将质心坐标提取出来保存
%                     delete(app.ax1);
                    imshow(inputImageOr, 'Parent', app.ax1);
                    title(app.ax1, ['Picture ', num2str(i)], "FontSize", app.FS);
                     hold(app.ax1, "off")

                    ia = 0;
                    for j = 1: numRegions
                        if  app.ROI(j).BoundingBox(3) <= 1.5 * app.pq(3) && app.ROI(j).BoundingBox(3) >=0.7*app.pq(3) ...
                                && app.ROI(j).BoundingBox(4) <= 1.5 * app.pq(4) && app.ROI(j).BoundingBox(3) >= 0.7 * app.pq(4)
                            ia = ia + 1;
                            ROII(ia) = app.ROI(j);
                        end
                    end

                    for j = 1: ia
                        cent(j, :) = ROII(j).Centroid;
                        x = ROII(j).BoundingBox(1);
                        y = ROII(j).BoundingBox(2);
                        W = ROII(j).BoundingBox(3);
                        H = ROII(j).BoundingBox(4);
                        rectangle('Position',[x, y,W,H],'EdgeColor','b','LineWidth',2, 'Parent', app.ax1);
                        text(app.ax1, cent(j, 1), cent(j, 2), num2str(j), 'Color', 'r', 'FontSize', app.FS, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                    end

                    if i == 1
                         centData{1} = cent;
                         centData{3} = cent;
                    else
                        centData{2} = centData{3};
                        centData{3} = cent;
                    end

                end
                %% 数据绘图               
                    % 计算位移向量
                    vectorC = centData{3} - centData{1};

                     % 累计位移与速度， diag获取矩阵对角元素
                    CD = sqrt(diag(vectorC * vectorC'))';
                    for ja = 1: num_rois
                        if CD(ja) < vectorC1(ja)
                            CD(ja) = vectorC1(ja);
                        end
                        vectorC1(ja) = CD(ja);
                    end
                    CumDisplacement = [CumDisplacement; CD .* pixel2cm];

                     % 移动平均滤波
                        windowSize = 5; % 窗口大小
                        CumDisplacement = movmean(CumDisplacement, windowSize, 1,'Endpoints','shrink');

                        app.UITable.Data = CumDisplacement;

                        cla(ax2); %清除子图二的图窗

                        % 累积位移绘图参数说明：1，app.ax2-绘图坐标区，X-横坐标，app.CumDisplacement-累积位移，app.FS-字体大小，num_rois-检测点数量
                        CumDisplacementPlot(app, ax2, X, CumDisplacement, FS, num_rois)
                        pause(0); % 暂停秒，便于观察


                clear app.inputImages
                

                 if (i >= pn && mod(i - pn, intern) == 0) || i ==  length(app.fileList) 
                     app.TextArea_5.Value = [num2str(app.itxt)];
           
                     app.itxt = app.itxt + 1;
                     %%
                     app.cellArrayText{1} = sprintf('%s\n', '***此处输出信息提示***'); % 赋初值
                     app.TextArea.Value=app.cellArrayText{1}; % 文本区域中的初始显示信息
                    %% GM（1，1）

                    data = CumDisplacement;
                    app.x0{app.itxt} = CumDisplacement(max(end-datan, 1): end, :); %app.x0
                    app.X1{app.itxt} = X(max(end-datan, 1): end);
                    n = length(app.x0{app.itxt});
                        ERROR = 0;  % 建立一个错误指标，一旦出错就指定为1

                        if sum(app.x0{app.itxt} < 0) > 0 
                            % x0<0返回一个逻辑数组(0-1组成)，如果有数据小于0，则所在位置为1，如果原始数据均为非负数，那么这个逻辑数组中全为0，求和后也是0~
                            errordlg('灰色预测的时间序列中不能有负数');                        
                            ERROR = 1;
                        end
                         if ERROR == 0   % 如果上述错误均没有发生时，才能执行下面的操作步骤
                             [app.x1{app.itxt}, app.rho{app.itxt}, index1, index2] = QuasiExponentialLaw(app, app.x0{app.itxt}, n);

                          for j = 1: num_rois
                            outPut(app,['指标1：第' num2str(j) '个监测点的数据的光滑比小于0.5的数据占比为',num2str(index1(j)),'%']);
                            outPut(app,['指标2：除去前两个时期外，第' num2str(j) '个监测点的数据的光滑比小于0.5的数据占比为',num2str(index2(j)),'%']);
                            outPut(app,'参考标准：指标1一般要大于60%, 指标2要大于90%');

                            if (index1(j) >60) && (index2(j) > 80)
                                Judge = 1;
                            elseif app.x0{app.itxt}(j, :) == zeros(length(app.x0{app.itxt}(j, :)), 1)
                                Judge = 1;
                            else
                                Judge = 0;
                            end

                            if Judge == 0
                                outPut(app,['灰色预测模型不适合第' num2str(j) '个监测点的数据~ 请考虑其他方法 例如ARIMA，指数平滑等']);
                                ERROR = 1;
                            end
                            outPut(app,'------------------------------------------------------------')
                          end

                         end

                           if ERROR == 0   % 如果上述错误均没有发生时，才能执行下面的操作步骤
                            if n > 7    % 将数据分为训练组和试验组(根据原数据量大小n来取，n小于7则取最后两年为试验组，n大于7则取最后三年为试验组)
                                app.test_num = round(n*0.3);
                            else
                                app.test_num = 2;
                            end
                            train_x0 = app.x0{app.itxt}(1:end-app.test_num, :);  % 训练数据
                           
                            app.test_x0{app.itxt} =  app.x0{app.itxt}(end-app.test_num+1:end, :); % 试验数据

                            app.result1{app.itxt} = gm11(app,train_x0, app.test_num); %使用传统的GM(1,1)模型对训练数据，并预测后test_num期的结果

                            app.tX{app.itxt} = app.X1{app.itxt}(end-app.test_num+1:end);  % 
                            % 先利用gm11函数得到对原数据拟合的详细结果
                             [result, x0_hat{app.itxt}, app.relative_residuals{app.itxt}, app.eta{app.itxt}] = gm11(app ,app.x0{app.itxt}, predict_num);  

                           
                            outPut(app, ' ')
                            outPut(app, '****下面将输出对原数据拟合的评价结果***')

                            %% 残差检验
                            average_relative_residuals = mean(app.relative_residuals{app.itxt}, 1);  % 计算平均相对残差 mean函数用来均值
                           outPut(app, ["第" num2str(j) '个监测点的平均相对残差为',num2str(average_relative_residuals)])
                           a = find((average_relative_residuals<0.1) == 1);
                           b = find((average_relative_residuals >= 0.1 & average_relative_residuals<0.2) == 1);
                           c = find((average_relative_residuals >= 0.2) == 1);
                           if ~isnan(a)
                                outPut(app,['监测点的残差检验的结果表明,该模型对第' num2str(a) '个监测点的数据的拟合程度非常不错'])
                           end
                           if ~isnan(b)
                                outPut(app,['监测点的残差检验的结果表明,该模型对第' num2str(b) '个监测点的数据的拟合程度达到一般要求'])
                           end
                           if ~isnan(c)
                                outPut(app,['监测点的残差检验的结果表明,该模型对第' num2str(c) '个监测点的数据的拟合程度不太好，建议使用其他模型预测'])
                           end

                            %% 级比偏差检验
                            average_eta = mean(app.eta{app.itxt}, 1);   % 计算平均级比偏差
                            outPut(app, ['监测点的平均级比偏差分别为',num2str(average_eta)])
                            a = find((average_eta<0.1) == 1);
                            b = find((average_eta >= 0.1 & average_eta<0.2) == 1);
                            c = find((average_eta >= 0.2) == 1);
                            if ~isnan(a)
                                outPut(app, ['监测点的级比偏差检验的结果表明：该模型对第' num2str(a) '个监测点的数据的拟合程度非常不错'])
                            end
                            if ~isnan(b)
                                outPut(app, ['监测点的级比偏差检验的结果表明：该模型对第' num2str(b) '个监测点的数据的拟合程度达到一般要求'])
                            end
                            if ~isnan(c)
                                outPut(app, ['监测点的级比偏差检验的结果表明：该模型对第' num2str(c) '个监测点的数据的拟合程度不太好，建议使用其他模型预测'])
                            end
                            outPut(app, ' ')
                            outPut(app, '------------------------------------------------------------')

                            v = diff(app.x0{app.itxt}) / app.interval;
                            V_average = mean(v);
                            v_average(app.itxt, :) = V_average;
                            dlta_T(app.itxt, :) = (app.x0{app.itxt}(end, :) - app.x0{app.itxt}(1, :));
%                             if app.itxt > 2
                                Angle = rad2deg(atan(dlta_T(app.itxt, :)-dlta_T(app.itxt-1, :) ./((app.X1{app.itxt}(end)- app.X1{app.itxt-1}(1)))));                             
                                angle = [angle; Angle];
                                Xangle = [Xangle,app.X1{app.itxt}(1)];

                               cla(ax3); %清除子图三的图窗
                                AnglePlot(app, ax3, Xangle, angle, num_rois, FS)

%                             end

                            if app.itxt > 3

                                for ip = 1: num_rois
                                    [~, minpeaks] = findpeaks(-angle(:, ip));
                                    min_positions{ip} = minpeaks;
                                    if ~isnan(min_positions{ip})

                                        for imp = 1: length(min_positions{ip})

                                            if (angle(min_positions{ip}(end), ip) > 10)
                                                min_positions{ip}(end) = [];
                                            end
                                        end
                                        if isempty(min_positions{ip})
                                            site(ip) = 1;
                                        else
                                            site(ip) = min_positions{ip}(end);
                                        end
                                        angle2{ip} = angle(site(ip): end, ip);
                                        valid_positions{ip} = find(angle2{ip} > 50);

                                        if ~isnan(valid_positions{ip})
                                            latest_position{ip} = site(ip) + valid_positions{ip}(1)- 1;
                                            site2(ip) = Xangle(latest_position{ip});
                                        end

                                    end

                                end
 
                            end
                            

                            %% 绘制最终的预测效果图
                            % 下面绘图中的符号m:洋红色 b:蓝色
                             cla(ax4) %清除绘图
                              preX =  app.X1{app.itxt}(end):app.interval: app.X1{app.itxt}(end)+(predict_num-1)* app.interval; % 预测数据的X轴坐标

                             PredictPlot(app, ax4, X, data, preX, app.X1{app.itxt},x0_hat{app.itxt}, result, site2, FS);
                             pause(0);

                             PredictLandslideTime(app, Value, result, app.interval)

                        clear result;

                           end
                        clear data;

                 end
%                    catch ME
%            errordlg('处理文件 %s 时出错: %s\n', fileName, ME.message);
%         end


                end

                % 更新最后处理计数
                lastProcessedCount = length(app.fileList);
            else

            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all
            clear
            clc
        end

        % Button pushed function: OpenFiles
        function OpenFilesPushed(app, event)
     
            path = uigetdir();
            app.folderPath = fullfile(path);
            app.EditField.Value = app.folderPath;
            if ~isfolder(app.folderPath)
                errordlg('指定的文件夹不存在: %s', app.folderPath);
            end
            
        end

        % Button pushed function: loading
        function loadingPushed(app, event)
            % 遍历所有支持的扩展名，获取文件列表
            app.fileList = filelistfcn(app, app.imageExtensions, app.folderPath, app.fileList);
            % 检查文件夹中是否有图像文件
            if isempty(app.fileList)
                 errordlg('文件夹中没有图像文件，等待新文件...\n');
            else
                fileName = fullfile(app.folderPath, app.fileList(1).name);
                inputImages = imread(fileName);

                %%
            app.ax1 = subplot(2, 2, 1, "Parent", app.DrawFigure);
            imshow(inputImages, "Parent", app.ax1)
            title(app.ax1, "Image display","FontSize", app.FS);
            [app.rows, app.cols, ~] = size(inputImages);

            fig = uifigure(app.V20Panel, 'Name', 'Frame and Display Range', 'Position', [300,300,634,473]);

%             uilabel(fig,"Text",'Frame and Display Range', 'Position', [150,290,20,20])
            t = uiaxes(fig, "Position", [0, 0, 634,473]);
            imshow(inputImages, "Parent", t);
            hold(t, "on");            
            rec = drawrectangle("Parent", t);%生成一个可拉伸的矩形
            app.p = ceil(rec.Position);%获取拉伸后矩阵的坐标
            close(fig);
            
           app.inputImage = inputImages(app.p(2):min(app.p(2)+app.p(4), app.rows), app.p(1):min(app.p(1)+app.p(3), app.cols), :);%截取矩形框中的图像
            imshow(app.inputImage, "Parent", app.ax1)
            title(app.ax1, "Image Clipping", "FontSize", app.FS);
            end
        end

        % Menu selected function: aboutsolftware
        function aboutsolftwareMenuSelected(app, event)
             fig = uifigure(app.V20Panel, 'Name', '关于软件', 'Position', [500,500,260,159]);
             uitextarea(fig, "Value",['本软件主要实现了带有监测点的边坡累计位移的预测，' ...
                 '其功能特点是边坡监测点图像识别并按批次处理以及快速计算累计位移和位移速度，' ...
                 '并预测其位移趋势和位移时间。并可选择查看软件运行过程中相关的分析内容。'], ...
                 'Position', [1,1,260,159]);

        end

        % Menu selected function: link
        function linkMenuSelected(app, event)
             fig = uifigure(app.V20Panel, 'Name', '联系我们', 'Position', [500,500,260,159]);
             str = {'作者： 李安成'
                 ' '
             '邮箱：2023110904@mail.hfut.edu.cn '
             ' '
             '电话：17355128959'
                 };
             uitextarea(fig, "Value",str, ...
                 'Position', [1,1,260,159]);
        end

        % Button pushed function: ChooseWay
        function ChooseWayPushed(app, event)
            %图像调整
%             fig = uifigure(app.V20Panel, 'Position', [500,500,174,221]);
%             uilabel(fig, 'Text', '降噪方式','Position', [41,193,100,23]);
%             btn = uibutton(fig,'push', ...
%                 'Text', '基于特征', ...
%                 'Position', [41,153,100,23], ...
%                 'ButtonPushedFcn',@(btn,event) featureButtonPushed(app, btn));

            data.inputImage = app.inputImage;
            setappdata(0, "ChooseWay", data);
            run Chooseway.mlapp;


        end

        % Button pushed function: countersign
        function countersignButtonPushed(app, event)
            featurevalue = getappdata(0, "chooseway");
            Fea = getappdata(0, "numpoint");
            Color = getappdata(0, "adjust");
            app.ChooseValue = featurevalue;
            if app.ChooseValue == 1
                data2 = Fea;
            elseif  app.ChooseValue == 0
                data2 = Color;
            end
            app.Fig1Para = data2;
            % % 基于颜色的识别，监测点尺寸换算
            % app.pq = getappdata(0, "Monitoringsize");
            imshow(data2.inputImages, "Parent", app.ax1)
            title(app.ax1, "Image display" ,"FontSize", app.FS);
            app.inputImage = data2.inputImages;
            app.Lamp.Color = "g";
        end

        % Button pushed function: sButton
        function sButtonPushed(app, event)
             % 显示预测的时长
            app.sTextArea.Value = num2str(app.predictValue.Value * app.sEditField.Value);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
         clear app.inputImage
         clear app.inputImages
         % Value表示边坡监测点至路面的距离
            Value = [(str2num(app.mEditField.Value))* 100];
            if length(Value) ~= app.Fig1Para.num_rois
               warndlg('请按框选监测点的顺序输入监测点至路面的距离，并检查输入个数是否与监测点个数一致');
            else
            
            %% 将累积位移和速度以及预测绘图固定到图窗上绘制子图
            % 子图绘制
            app.ax2 = subplot(2, 2, 2, "Parent", app.DrawFigure);
            app.ax3 = subplot(2, 2, 3, "Parent", app.DrawFigure);
            app.ax4 = subplot(2, 2, 4, "Parent", app.DrawFigure);
            %%
            app.interval = app.sEditField.Value; % 间隔时间
            predict_num = app.predictValue.Value;
            X = [];  %初始化X轴
            
           
             Xangle = 0;
            if app.ChooseValue == 1

                 p2 = app.Fig1Para.p;
                        cent = app.Fig1Para.cent;
                        centData{1} = cent;
                        centData{3} = cent;
                        mask1 = app.Fig1Para.mask1;

                pixel2cm = (app.Fig1Para.widht + app.Fig1Para.height)/2;

                num_rois = app.Fig1Para.num_rois;
                app.TextArea_6.Value = [num2str(num_rois)];
                ia = num_rois; %监测点个数

                searchRange = app.Fig1Para.searchRange;
                idx = -searchRange:searchRange;

                % [X,Y] = meshgrid(x,y)基于向量 x 和 y 中包含的坐标返回二维网格坐标。
                % X 是一个矩阵，每一行是 x 的一个副本； Y 也是一个矩阵，每一列是 y 的一个副本。
                % 坐标 X 和 Y 表示的网格有 length(y) 个行和length(x) 个列。
                [dX,dY] = meshgrid(idx, idx);
                % 限制横向和纵向上的范围
                dX = dX(:, round(0.7*searchRange): round(1.3*searchRange));
                dY = dY(round(0.9 * searchRange): round(1.5 * searchRange), :);
               
                myCluster = parcluster('Processes');
                delete(myCluster.Jobs);
                parpool("Processes", 5);

            elseif app.ChooseValue == 0

                PhotoLayer = app.Fig1Para.PL;
                app.pq = app.Fig1Para.p;
                pixel2cm = (app.Fig1Para.widht + app.Fig1Para.height)/2;
            end

            app.CumDisplacement = zeros(1, ia);
            app.speed = zeros(1, ia);
             site2 = zeros(1, ia);
             vectorC1 = zeros(1, ia);
         % 表列命名
           for j = 1: ia

               app.UITable.ColumnName{j} = [ '监测点'  num2str(j) '的累计位移'];
                app.UITable2.ColumnName{j} = [ '监测点' num2str(j) '的位移速度'];
           end

           angle =  zeros(1, num_rois);
            
%% 主循环
for i = 1:length(app.fileList)
        fileName = fullfile(app.folderPath, app.fileList(i).name);
%         try
            % 读取图像
            X = [X, i * app.interval];
            app.numi = i;
            inputImages = imread(fileName);
            %裁剪和调整对比度
            inputImageOr = inputImages(app.p(2):min(app.p(2)+app.p(4), app.rows), app.p(1):min(app.p(1)+app.p(3), app.cols), :);
               
                %% 基于特征
                if app.ChooseValue == 1
                    
                    if i == 1

                    else
                        [p2, bestMatch] = CalculateNewSite(app, inputImageOr, num_rois, dX, dY, p2, mask1);
                        cla(app.ax1); %清除子图一的图窗
%                         cent = DisplayPoint(app, app.fileList(i).date, inputImageOr, app.ax1, app.FS, num_rois, bestMatch);
                           strp = ['Picture', num2str(i)];
                           cent = DisplayPoint(app,  strp, inputImageOr, app.ax1, app.FS, num_rois, bestMatch);
                    end
                    centData{2} = centData{3};
                    centData{3} = cent;

                    %% 基于颜色

                elseif app.ChooseValue == 0

                    inputImages = histeq(inputImageOr);
                    inputImages = imadjust(inputImages, app.Fig1Para.ColorPara, []);
                    inputImages = inputImages(:, :, PhotoLayer);
                    inputImages = ColorThreshold( inputImages, app.Fig1Para.UCT, app.Fig1Para.LCT, app.Fig1Para.threshold);

                    % 将特征进行腐蚀和膨胀，使特征分离开
                    SE = strel('square',app.Fig1Para.strelPa);
                    inputImages = imfill(inputImages, 'holes');
                    inputImages = imerode(inputImages, SE);
                    [labeledImg, numRegions] = bwlabel(inputImages);  % 分割图像，并返回标记图像和区域数量
                    app.ROI = regionprops(labeledImg);   % 测量图像区域的属性,像素大小，边界框，质心
                    %将质心坐标提取出来保存
%                     delete(app.ax1);
                    imshow(inputImageOr, 'Parent', app.ax1);
                    title(app.ax1, ['Picture ', num2str(i)], "FontSize", app.FS);
                     hold(app.ax1, "off")

                    ia = 0;
                    for j = 1: numRegions
                        if  app.ROI(j).BoundingBox(3) <= 1.5 * app.pq(3) && app.ROI(j).BoundingBox(3) >=0.7*app.pq(3) ...
                                && app.ROI(j).BoundingBox(4) <= 1.5 * app.pq(4) && app.ROI(j).BoundingBox(3) >= 0.7 * app.pq(4)
                            ia = ia + 1;
                            ROII(ia) = app.ROI(j);
                        end
                    end

                    for j = 1: ia
                        cent(j, :) = ROII(j).Centroid;
                        x = ROII(j).BoundingBox(1);
                        y = ROII(j).BoundingBox(2);
                        W = ROII(j).BoundingBox(3);
                        H = ROII(j).BoundingBox(4);
                        rectangle('Position',[x, y,W,H],'EdgeColor','b','LineWidth',2, 'Parent', app.ax1);
                        text(app.ax1, cent(j, 1), cent(j, 2), num2str(j), 'Color', 'r', 'FontSize', app.FS, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                    end

                    if i == 1
                         centData{1} = cent;
                         centData{3} = cent;
                    else
                        centData{2} = centData{3};
                        centData{3} = cent;
                    end

                end
                %% 数据绘图
                if app.numi == 1

                else
                    % 计算位移向量
                    vectorC = centData{3} - centData{1};

                     % 累计位移与速度， diag获取矩阵对角元素
                    CD = sqrt(diag(vectorC * vectorC'))';
                    for ja = 1: ia
                    if CD(ja) < vectorC1(ja)
                        CD(ja) = vectorC1(ja);
                    end
                    vectorC1(ja) = CD(ja);
                    end

%                     Speed = diag(sqrt(vectorS * vectorS'))' ./ app.interval;
                    app.CumDisplacement = [app.CumDisplacement; CD .* pixel2cm];
%                     app.speed = [app.speed; Speed .* pixel2cm];

                     % 移动平均滤波
                        windowSize = 5; % 窗口大小
                        app.CumDisplacement = movmean(app.CumDisplacement, windowSize, 1,'Endpoints','shrink');
%                         app.speed = movmean(app.speed, windowSize, 1, 'Endpoints','shrink');
                        app.CumDisplacement(1, :) = 0;

                        app.UITable.Data = app.CumDisplacement;
%                         app.UITable2.Data = app.speed;
                        cla(app.ax2); %清除子图二的图窗

                        % 累积位移绘图参数说明：1，app.ax2-绘图坐标区，X-横坐标，app.CumDisplacement-累积位移，app.FS-字体大小，ia-检测点数量
                        CumDisplacementPlot(app, app.ax2, X, app.CumDisplacement, app.FS, ia)
                        pause(0); % 暂停秒，便于观察
%                         SpeedPlot(app, app.ax3, X, app.speed, app.FS, ia)

                end
                clear app.inputImages
                

                 if (i >= app.pn.Value && mod(i - app.pn.Value, app.intern.Value) == 0) || i ==  length(app.fileList) 
                     % pn.Value 起始数据量
                     % intern.Value 预测间隔

                     app.TextArea_5.Value = [num2str(app.itxt)];
           
                     app.itxt = app.itxt + 1;
                     %%
                     app.cellArrayText{1} = sprintf('%s\n', '***此处输出信息提示***'); % 赋初值
                     app.TextArea.Value=app.cellArrayText{1}; % 文本区域中的初始显示信息
                    %% GM（1，1）

                    data = app.CumDisplacement;
                    app.x0{app.itxt} = app.CumDisplacement(max(end-app.datan.Value, 1): end, :); %app.x0
                    app.X1{app.itxt} = X(max(end-app.datan.Value, 1): end);
                    n = length(app.x0{app.itxt});
                        ERROR = 0;  % 建立一个错误指标，一旦出错就指定为1

                        if sum(app.x0{app.itxt} < 0) > 0 
                            % x0<0返回一个逻辑数组(0-1组成)，如果有数据小于0，则所在位置为1，如果原始数据均为非负数，那么这个逻辑数组中全为0，求和后也是0~
                            errordlg('灰色预测的时间序列中不能有负数');                        
                            ERROR = 1;
                        end
                         if ERROR == 0   % 如果上述错误均没有发生时，才能执行下面的操作步骤
                             [app.x1{app.itxt}, app.rho{app.itxt}, index1, index2] = QuasiExponentialLaw(app, app.x0{app.itxt}, n);
%                             outPut(app,'------------------------------------------------------------');
%                             outPut(app,'准指数规律检验');
%                             app.x1{app.itxt} = cumsum(app.x0{app.itxt});   % 生成1-AGO序列，cumsum是累加函数~    注意：1.0e+03 *0.1740的意思是科学计数法,即10^3*0.1740 = 174
%                             app.rho{app.itxt} = app.x0{app.itxt}(2:end, :) ./ app.x1{app.itxt}(1:end-1, :) ; % 计算光滑度rho(k) = x0(k)/x1(k-1)
% 
%                             index1 = 100*sum(app.rho{app.itxt}<0.5, 1) ./ (n-1);
%                             index2 = 100*sum(app.rho{app.itxt}(3:end, :)<0.5, 1) ./ (n-3);

                          for j = 1: ia
                            outPut(app,['指标1：第' num2str(j) '个监测点的数据的光滑比小于0.5的数据占比为',num2str(index1(j)),'%']);
                            outPut(app,['指标2：除去前两个时期外，第' num2str(j) '个监测点的数据的光滑比小于0.5的数据占比为',num2str(index2(j)),'%']);
                            outPut(app,'参考标准：指标1一般要大于60%, 指标2要大于90%');

                            if (index1(j) >60) && (index2(j) > 80)
                                Judge = 1;
                            elseif app.x0{app.itxt}(j, :) == zeros(length(app.x0{app.itxt}(j, :)), 1)
                                Judge = 1;
                            else
                                Judge = 0;
                            end

                            if Judge == 0
                                outPut(app,['灰色预测模型不适合第' num2str(j) '个监测点的数据~ 请考虑其他方法 例如ARIMA，指数平滑等']);
                                ERROR = 1;
                            end
                            outPut(app,'------------------------------------------------------------')
                          end

                         end

                           if ERROR == 0   % 如果上述错误均没有发生时，才能执行下面的操作步骤
                            if n > 7    % 将数据分为训练组和试验组(根据原数据量大小n来取，n小于7则取最后两年为试验组，n大于7则取最后三年为试验组)
                                app.test_num = round(n*0.3);
                            else
                                app.test_num = 2;
                            end
                            train_x0 = app.x0{app.itxt}(1:end-app.test_num, :);  % 训练数据
                           
                            app.test_x0{app.itxt} =  app.x0{app.itxt}(end-app.test_num+1:end, :); % 试验数据

                            app.result1{app.itxt} = gm11(app,train_x0, app.test_num); %使用传统的GM(1,1)模型对训练数据，并预测后test_num期的结果

                            app.tX{app.itxt} = app.X1{app.itxt}(end-app.test_num+1:end);  % 
                            % 先利用gm11函数得到对原数据拟合的详细结果
                             [result, x0_hat{app.itxt}, app.relative_residuals{app.itxt}, app.eta{app.itxt}] = gm11(app ,app.x0{app.itxt}, predict_num);  

                           
                            outPut(app, ' ')
                            outPut(app, '****下面将输出对原数据拟合的评价结果***')

                            %% 残差检验
                            average_relative_residuals = mean(app.relative_residuals{app.itxt}, 1);  % 计算平均相对残差 mean函数用来均值
                           outPut(app, ["第" num2str(j) '个监测点的平均相对残差为',num2str(average_relative_residuals)])
                           a = find((average_relative_residuals<0.1) == 1);
                           b = find((average_relative_residuals >= 0.1 & average_relative_residuals<0.2) == 1);
                           c = find((average_relative_residuals >= 0.2) == 1);
                           if ~isnan(a)
                                outPut(app,['监测点的残差检验的结果表明,该模型对第' num2str(a) '个监测点的数据的拟合程度非常不错'])
                           end
                           if ~isnan(b)
                                outPut(app,['监测点的残差检验的结果表明,该模型对第' num2str(b) '个监测点的数据的拟合程度达到一般要求'])
                           end
                           if ~isnan(c)
                                outPut(app,['监测点的残差检验的结果表明,该模型对第' num2str(c) '个监测点的数据的拟合程度不太好，建议使用其他模型预测'])
                           end

                            %% 级比偏差检验
                            average_eta = mean(app.eta{app.itxt}, 1);   % 计算平均级比偏差
                            outPut(app, ['监测点的平均级比偏差分别为',num2str(average_eta)])
                            a = find((average_eta<0.1) == 1);
                            b = find((average_eta >= 0.1 & average_eta<0.2) == 1);
                            c = find((average_eta >= 0.2) == 1);
                            if ~isnan(a)
                                outPut(app, ['监测点的级比偏差检验的结果表明：该模型对第' num2str(a) '个监测点的数据的拟合程度非常不错'])
                            end
                            if ~isnan(b)
                                outPut(app, ['监测点的级比偏差检验的结果表明：该模型对第' num2str(b) '个监测点的数据的拟合程度达到一般要求'])
                            end
                            if ~isnan(c)
                                outPut(app, ['监测点的级比偏差检验的结果表明：该模型对第' num2str(c) '个监测点的数据的拟合程度不太好，建议使用其他模型预测'])
                            end
                            outPut(app, ' ')
                            outPut(app, '------------------------------------------------------------')

                            v = diff(app.x0{app.itxt}) / app.interval;
                            V_average = mean(v);
                            v_average(app.itxt, :) = V_average;
                            dlta_T(app.itxt, :) = (app.x0{app.itxt}(end, :) - app.x0{app.itxt}(1, :));
                            if app.itxt > 2
                                Angle = rad2deg(atan(dlta_T(app.itxt, :)-dlta_T(app.itxt-1, :) ./((app.X1{app.itxt}(end)- app.X1{app.itxt-1}(1)))));                             
                                angle = [angle; Angle];
                                Xangle = [Xangle,app.X1{app.itxt}(1)];

                               cla(app.ax3); %清除子图三的图窗
                                AnglePlot(app, app.ax3, Xangle, angle, ia, app.FS)

                            end

                            if app.itxt > 3

                                for ip = 1: ia
                                    [~, minpeaks] = findpeaks(-angle(:, ip));
                                    min_positions{ip} = minpeaks;
                                    if ~isnan(min_positions{ip})

                                        for imp = 1: length(min_positions{ip})

                                            if (angle(min_positions{ip}(end), ip) > 20)
                                                min_positions{ip}(end) = [];
                                            end
                                        end
                                        if isempty(min_positions{ip})
                                            site(ip) = 1;
                                        else
                                            site(ip) = min_positions{ip}(end);
                                        end
                                        angle2{ip} = angle(site(ip): end, ip);
                                        valid_positions{ip} = find(angle2{ip} > 45);

                                        if ~isnan(valid_positions{ip})
                                            latest_position{ip} = site(ip) + valid_positions{ip}(1)- 1;
                                            site2(ip) = Xangle(latest_position{ip});
                                        end

                                    end

                                end
 
                            end
                            

                            %% 绘制最终的预测效果图
                            % 下面绘图中的符号m:洋红色 b:蓝色
                             cla(app.ax4) %清除绘图
                              preX =  app.X1{app.itxt}(end):app.interval: app.X1{app.itxt}(end)+(predict_num-1)* app.interval; % 预测数据的X轴坐标

                             PredictPlot(app, app.ax4, X, data, preX, app.X1{app.itxt},x0_hat{app.itxt}, result, site2, app.FS);
                             pause(0);

                             PredictLandslideTime(app, Value, result, app.interval)

                        clear result;

                           end
                        clear data;

                 end
%                    catch ME
%            errordlg('处理文件 %s 时出错: %s\n', fileName, ME.message);
%         end
end
%% 新加入的图片处理

% 设置定时器对象来监控文件夹
app.timerObj = timer(...
    'ExecutionMode', 'fixedRate', ...       % 固定速率执行
    'Period', app.interval, ...                        % 每间隔拍摄间隔查一次（可根据需要调整）
    'TimerFcn', @(~,~)processImages(app, length(app.fileList), app.folderPath, X, app.p,app.rows, app.cols,  num_rois, p2, app.CumDisplacement, vectorC1, ...
                app.imageExtensions, app.interval, app.ChooseValue, dX, dY, mask1, pixel2cm, app.ax1, app.ax2, app.ax3, app.ax4, ...
                app.FS, app.pn.Value, app.intern.Value, app.datan.Value, centData, predict_num, site2, angle, Xangle, Value), ...
    'StopFcn', @(~,~)msgbox('监控已停止\n'), ...
    'TasksToExecute', Inf);                 % 无限执行

% 启动定时器
start(app.timerObj);

 % 保持脚本运行
while isvalid(app.timerObj)
    pause(1);
end
 % 停止定时器
 stop(app.timerObj);
 % 清理定时器
delete(app.timerObj);
clear timerObj;
           
            end

        end

        % Value changed function: Button_12
        function Button_12ValueChanged(app, event)
      
        if app.Button_12.Value == 1  
            i = app.cumputer_point.Value;
            j = app.moniter_point.Value;
            if i > app.itxt && j > app.Fig1Para.num_rois
                warndlg( ['次数超过最大限制，取值应小于',num2str(i), '监测点数超过最大限制，取值应小于',num2str(j)]);
            elseif i > app.itxt
                warndlg( ['次数超过最大限制，取值应小于',num2str(i)]);
            elseif j > app.Fig1Para.num_rois
               warndlg( ['监测点数超过最大限制，取值应小于',num2str(j)]);
           else

               %% 光滑度
            figure(2)
            plot(app.X1{i}(2:end),app.rho{i}(:, j),'o-',[app.X1{i}(2),app.X1{i}(end)],[0.5,0.5],'-'); grid on;
            text(app.X1{i}(end-3), 0.55 ,'Critical line',"FontSize", app.FS)   % 在坐标(year(end-3),0.55)上添加文本
%             set(gca,'xtick',app.X1{i}(2:20:end))  % 设置x轴横坐标的间隔为20
            xlabel('time (s)',"FontSize", app.FS); 
            ylabel('The smoothness of the raw data',"FontSize", app.FS);  % 给坐标轴加上标签
           %% 原始数据和预测数据对比
            figure(3)
            
            plot( app.tX{i},app.test_x0{i}(j),'-or',app.tX{i}, app.result1{i}(j),'-*b'); grid on;
%             set(gca,'xtick',app.X1{i}(end-app.test_num+2): 20 :app.X1{i}(end))  % 设置x轴横坐标的间隔为20
            legend('Real data for the experimental group ',' predicted results of GM(1,1)' ,"FontSize", app.FS)  % 注意：如果lengend挡着了图形中的直线，那么lengend的位置可以自己手动拖动
            xlabel( 'time (s)',"FontSize", app.FS);  
            ylabel('Cumulative displacement (cm)',"FontSize", app.FS);   % 给坐标轴加上标签

            %% 残差和级比偏差图
            figure(4)
            subplot(2,1,1)  % 绘制子图（将图分块）
            plot(app.X1{i}(2:end), app.relative_residuals{i}(j),'*-'); grid on;   % 原数据中的各时期和相对残差
            legend('relative residuals',"FontSize", app.FS);
            xlabel('time (s)',"FontSize", app.FS);
%             set(gca,'xtick',app.X1{i}(2:20:end))  % 设置x轴横坐标的间隔为20
            subplot(2,1,2)
            plot(app.X1{i}(2:end), app.eta{i}(j),'o-'); grid on;   % 原数据中的各时期和级比偏差
            legend('eta',"FontSize", app.FS); 
            xlabel('time (s)',"FontSize", app.FS);
%             set(gca,'xtick',app.X1{i}(2:20:end))  % 设置x轴横坐标的间隔为20
            
           end
       end
            app.Button_12.Value = 0;
            app.Lamp2.Color = "0.94,0.94,0.94";
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            app.CumdisplacementTableData = app.UITable.Data;
            uialert(app.UIFigure, '表格数据已保存', '保存成功');
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            if isempty(app.CumdisplacementTableData)
                app.CumdisplacementTableData = app.UITable.Data;
            end

            [filename, pathname] = uiputfile('*.xlsx', '导出为Excel');
            if filename ~= 0
                try
                    writematrix(app.UITable.Data, fullfile(pathname, filename));
                    uialert(app.UIFigure, '导出成功!', '完成');
                catch ME
                    uialert(app.UIFigure, ME.message, '导出失败', 'Icon', 'error');
                    
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.V20Panel = uipanel(app.UIFigure);
            app.V20Panel.Title = '公路边坡蠕滑监测预报系统V2.0';
            app.DisplayPath = uipanel(app.V20Panel);
            app.DisplayPath.Title = '显示文件路径';
            app.EditFieldLabel = uilabel(app.DisplayPath);
            app.EditFieldLabel.Text = '文件路径';
            app.EditField = uieditfield(app.DisplayPath, 'text');
            app.OpenFiles = uibutton(app.DisplayPath, 'push');
            app.OpenFiles.ButtonPushedFcn = createCallbackFcn(app, @OpenFilesPushed, true);
            app.OpenFiles.Text = '打开文件';
            app.loading = uibutton(app.DisplayPath, 'push');
            app.loading.ButtonPushedFcn = createCallbackFcn(app, @loadingPushed, true);
            app.loading.Tag = 'loading';
            app.loading.Text = '加载';
            app.help = uibutton(app.DisplayPath, 'push');
            app.help.Text = '右键帮助';
            app.DrawFigure = uipanel(app.V20Panel);
            app.DrawFigure.Title = '显示区';
            app.Input = uiaxes(app.DrawFigure);
            app.Input.XTick = [];
            app.Input.YTick = [];
            app.Panel_3 = uipanel(app.V20Panel);
            app.Panel_3.Title = '调整区';
            app.TabGroup2 = uitabgroup(app.Panel_3);
            app.Tab_4 = uitab(app.TabGroup2);
            app.Tab_4.Title = '计算';
            app.Button_8 = uibutton(app.Tab_4, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '执行运算';
            app.sLabel = uilabel(app.Tab_4);
            app.sLabel.Text = '拍摄间隔时间(s)';
            app.sEditField = uieditfield(app.Tab_4, 'numeric');
            app.sEditField.Value = 20;
            app.Button_12 = uibutton(app.Tab_4, 'state');
            app.Button_12.ValueChangedFcn = createCallbackFcn(app, @Button_12ValueChanged, true);
            app.Button_12.Tag = 'dataillstrate';
            app.Button_12.Text = '绘图显示';
            app.Lamp2 = uilamp(app.Tab_4);
            app.Spinner_7Label_3 = uilabel(app.Tab_4);
            app.Spinner_7Label_3.Text = '结果分析(次，点）';
            app.moniter_point = uispinner(app.Tab_4);
            app.moniter_point.Limits = [1 Inf];
            app.moniter_point.Value = 1;
            app.Label_8 = uilabel(app.Tab_4);
            app.Label_8.Text = '预测数据量';
            app.predictValue = uieditfield(app.Tab_4, 'numeric');
            app.predictValue.Value = 20;
            app.sTextArea = uitextarea(app.Tab_4);
            app.sButton = uibutton(app.Tab_4, 'push');
            app.sButton.ButtonPushedFcn = createCallbackFcn(app, @sButtonPushed, true);
            app.sButton.Text = '预测时长(s)';
            app.Label_11 = uilabel(app.Tab_4);
            app.Label_11.Text = '起始数据量';
            app.pn = uieditfield(app.Tab_4, 'numeric');
            app.pn.Value = 15;
            app.Label_12 = uilabel(app.Tab_4);
            app.Label_12.Text = '预测间隔';
            app.intern = uieditfield(app.Tab_4, 'numeric');
            app.intern.Value = 5;
            app.Label_13 = uilabel(app.Tab_4);
            app.Label_13.Text = '样本数据量';
            app.datan = uieditfield(app.Tab_4, 'numeric');
            app.datan.Value = 20;
            app.cumputer_point = uispinner(app.Tab_4);
            app.cumputer_point.Limits = [1 Inf];
            app.cumputer_point.Value = 1;
            app.Label_16 = uilabel(app.Tab_4);
            app.Label_16.Text = '最大运算次数';
            app.TextArea_5 = uitextarea(app.Tab_4);
            app.Label_17 = uilabel(app.Tab_4);
            app.Label_17.Text = '最大监测点数';
            app.TextArea_6 = uitextarea(app.Tab_4);
            app.mEditField_2Label = uilabel(app.Tab_4);
            app.mEditField_2Label.Text = {'每个监测点至'; '路面距离(m)'};
            app.mEditField = uieditfield(app.Tab_4, 'text');
            app.Tab_5 = uitab(app.TabGroup2);
            app.Tab_5.Title = '数据1';
            app.UITable = uitable(app.Tab_5);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.Button_13 = uibutton(app.Tab_5, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '导出数据';
            app.Button_15 = uibutton(app.Tab_5, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '保存数据';
            app.Tab_6 = uitab(app.TabGroup2);
            app.Tab_6.Title = '数据2';
            app.UITable2 = uitable(app.Tab_6);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.Button_14 = uibutton(app.Tab_6, 'push');
            app.Button_14.Text = '导出数据';
            app.ChooseWay = uibutton(app.Panel_3, 'push');
            app.ChooseWay.ButtonPushedFcn = createCallbackFcn(app, @ChooseWayPushed, true);
            app.ChooseWay.Tag = 'ChooseWay';
            app.ChooseWay.Text = '选择降噪模式';
            app.countersign = uibutton(app.Panel_3, 'push');
            app.countersign.ButtonPushedFcn = createCallbackFcn(app, @countersignButtonPushed, true);
            app.countersign.Text = '确认';
            app.Lamp = uilamp(app.Panel_3);
            app.Label_10 = uilabel(app.Panel_3);
            app.Label_10.Text = '预测信息';
            app.TextArea_4 = uitextarea(app.Panel_3);
            app.Panel_12 = uipanel(app.V20Panel);
            app.Panel_12.Title = '信息输出区';
            app.TextArea = uitextarea(app.Panel_12);
            app.TextArea.Editable = 'off';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.aboutsolftware = uimenu(app.ContextMenu);
            app.aboutsolftware.MenuSelectedFcn = createCallbackFcn(app, @aboutsolftwareMenuSelected, true);
            app.aboutsolftware.Text = '关于软件';
            app.link = uimenu(app.ContextMenu);
            app.link.MenuSelectedFcn = createCallbackFcn(app, @linkMenuSelected, true);
            app.link.Text = '联系我们';
            % Assign app.ContextMenu
            app.help.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end