classdef numPoint < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Panel     matlab.ui.container.Panel
        Height    matlab.ui.control.NumericEditField
        Label_3   matlab.ui.control.Label
        Widht     matlab.ui.control.NumericEditField
        Label_4   matlab.ui.control.Label
        Button    matlab.ui.control.Button
        number    matlab.ui.control.Spinner
        Label     matlab.ui.control.Label
    end

    
    properties (Access = private)
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)


            feature = getappdata(0, 'feature');
            inputImages = feature.inputImages;            
            num_rois = app.number.Value;
             rect = cell(1, num_rois);
              p = cell(1, num_rois);
              bestMatch = cell(num_rois);
             cent = zeros(num_rois, 2);


             fig = uifigure(app.Panel, 'Name', '框选监测点', 'Position', [300,300,634,473]);
              t = uiaxes(fig, "Position", [0, 0, 634,473]);
            imshow(inputImages, "Parent", t);
            hold(t, "on");  
            for k = 1:num_rois
                % 框选一个矩形区域
                rect{k} = drawrectangle("Parent", t);
                %获取拉伸后矩阵的坐标
                p{k} = ceil(rect{k}.Position);
                % 在图片上显示框选的区域
                mask1{k} = inputImages(p{k}(2):p{k}(2)+p{k}(4), ...
                    p{k}(1):p{k}(1)+p{k}(3), :);
                % 最佳得分
                bestMatch{k} = p{k};
                % 获取质心坐标
                cent(k, 1) = bestMatch{k}(1) + bestMatch{k}(3)/2;
                cent(k, 2) = bestMatch{k}(2) + bestMatch{k}(3)/2;
            end
            close(fig);

            data.p = p;
            data.mask1 = mask1;
            data.cent = cent;
            data.num_rois = num_rois;
            data.widht =  app.Widht.Value ./  p{1}(3);
            data.height = app.Height.Value ./ p{1}(4);
            data.inputImages = inputImages;
            % 搜索范围（像素）,不超过350个像素点
            data.searchRange = min(350, 5 * max(p{1}(3), p{1}(4)));
            setappdata(0, "numpoint", data)
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '选择监测点数量';
            app.Panel.Tag = 'chooseway';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '监测点数量';
            app.number = uispinner(app.Panel);
            app.number.Limits = [0 Inf];
            app.number.Tag = 'numpoint';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确认';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '监测点尺寸：宽';
            app.Widht = uieditfield(app.Panel, 'numeric');
            app.Widht.Value = 30;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '监测点尺寸：高';
            app.Height = uieditfield(app.Panel, 'numeric');
            app.Height.Value = 30;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = numPoint

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end